classdef project_ppt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GioiThieuTab                 matlab.ui.container.Tab
        Image2                       matlab.ui.control.Image
        UITable                      matlab.ui.control.Table
        Image                        matlab.ui.control.Image
        MSSV23207064Label            matlab.ui.control.Label
        Lp23DTV_CLC1Label            matlab.ui.control.Label
        HtnBiGiaHuyLabel             matlab.ui.control.Label
        nngdnggiitonphngphptnhLabel  matlab.ui.control.Label
        MnhcPhngphptnhLabel          matlab.ui.control.Label
        NghiemTab                    matlab.ui.container.Tab
        EndEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        resultEditField              matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        ppListBox                    matlab.ui.control.ListBox
        PhngphpListBoxLabel          matlab.ui.control.Label
        errorEditField               matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel  matlab.ui.control.Label
        NhpkhongphnlynghimABLabel    matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        BEditFieldLabel              matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        AEditFieldLabel              matlab.ui.control.Label
        inputEditField               matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        RUNButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        He_phuong_trinh_Tab          matlab.ui.container.Tab
        Ma_tran_ket_qua              matlab.ui.control.EditField
        AEditField_2Label_4          matlab.ui.control.Label
        Ma_tran_b                    matlab.ui.control.EditField
        AEditField_2Label_3          matlab.ui.control.Label
        Ma_tran_A                    matlab.ui.control.EditField
        AEditField_2Label_2          matlab.ui.control.Label
        RUNButton_he_phuong_trinh    matlab.ui.control.Button
        qua_trinh_giai_he            matlab.ui.control.TextArea
        QutrnhthchinTextAreaLabel    matlab.ui.control.Label
        method_drop_down             matlab.ui.control.DropDown
        PhngphpDropDownLabel         matlab.ui.control.Label
        Noi_suy_Tab                  matlab.ui.container.Tab
        daThucEditField              matlab.ui.control.EditField
        aThcEditFieldLabel           matlab.ui.control.Label
        statusLabel                  matlab.ui.control.Label
        diemNoiSuyEditField          matlab.ui.control.EditField
        imnisuyEditFieldLabel        matlab.ui.control.Label
        KetquaEditField              matlab.ui.control.EditField
        KtquEditFieldLabel_2         matlab.ui.control.Label
        YEditField                   matlab.ui.control.EditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.EditField
        XEditFieldLabel              matlab.ui.control.Label
        MethodSelect                 matlab.ui.control.DropDown
        PhngphpDropDownLabel_2       matlab.ui.control.Label
        RUN_noi_suy                  matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        PhngtrnhviphnTab             matlab.ui.container.Tab
        NEditField                   matlab.ui.control.EditField
        NEditFieldLabel              matlab.ui.control.Label
        MethodSelect_2               matlab.ui.control.DropDown
        PhngphpDropDownLabel_3       matlab.ui.control.Label
        RUN_vi_phan                  matlab.ui.control.Button
        Y0EditField                  matlab.ui.control.EditField
        Y_0Label                     matlab.ui.control.Label
        ABEditField                  matlab.ui.control.EditField
        ABLabel                      matlab.ui.control.Label
        PhuongTrinhViPhanEditField   matlab.ui.control.EditField
        PhngtrnhviphnEditFieldLabel  matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        flag = 0; % Description
        c = -2;
    end
    
    methods (Access = private)
        
        %% Hàm tìm nghiệm
        function [x, n] = chiadoi(app, fx, a, b, err)
            % Kiểm tra hàm số liên tục trong khoảng phân ly không
            if fx(a)*fx(b) >= 0
                uialert(app.UIFigure,['Hàm số không liên tục trong khoảng phân ly [a, b]. ' ...
                    'Vui lòng nhập lại [a, b] hoặc hàm số khác!'], 'lỗi');
                x = NaN; n = 0; return;
            end

            n = 1; % Số lần lặp
            max_in = 1000;
            while n < max_in
                x = (a + b)/2;

                if fx(x)*fx(b) > 0
                    b = x;
                else 
                    a = x;
                end

                if abs(b - a) < err
                return;
                end
            n = n + 1;
            end
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
            end
        
        function [x, n] = lap(app, g, a, b, err)
            % Kiểm tra đạo hàm của g có q < 1 trong đoạn [a, b]
            syms x;
            g_sym = str2sym(g);
            dg_sym = diff(g_sym,x);
            dG = matlabFunction(dg_sym);disp(dG)
            if double(dG(a)) > 1 || double(dG(b)) > 1
                uialert(app.UIFigure, ['Đạo hàm g(x) = q > 1, không hội tụ! Vui lòng nhập lại hàm g(x) ' ...
                    'hoặc khoảng phân ly [a, b] khác'], 'Lỗi');
                x = NaN; n = 0; return;
            end
            
            g = str2func(['@(x)', g]);
            n = 1;
            %x0 = (a+b)/2;
            x0 = a;
            max_in = 1000;
            while n < max_in
                x = g(x0);

                % Kiểm tra sai số
                if abs(x - x0) < err
                    return;
                end
                
            n = n + 1;
            x0 = x;
            end
            x = x0; % Nếu vượt quá x bằng giá trị của số lần lặp tối đa
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end
        
        function [x, n] = newton_raphson(app, fx, a, b, err)
            % Tính đạo hàm
            try
            syms x;
            f_sym = str2sym(fx);

            df_sym = diff(f_sym, x);
            ddf_sym = diff(df_sym, x);

            % Chuyển về hàm số
            F = matlabFunction(f_sym);%disp(F);
            dF = matlabFunction(df_sym);%disp(dF);
            ddF = matlabFunction(ddf_sym);%disp(ddF);
            catch
                uialert(app.UIFigure, 'Lỗi cú pháp phương trình!', 'Lỗi');
                x = NaN; n = 0; return;
            end

            if F(a)*F(b) >= 0
                uialert(app.UIFigure,['Hàm số không liên tục trong khoảng phân ly [a, b]. ' ...
                    'Vui lòng nhập lại [a, b] hoặc hàm số khác!'], 'lỗi');
                x = NaN; n = 0; return;
            end

            % Xét điều kiện hội tụ
            if double(dF(a))*double(dF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 1 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            elseif double(ddF(a))*double(ddF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 2 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            end
            
            if double(F(a))*double(ddF(a)) > 0
                x0 = a;
            else 
                x0 = b;
            end

            %x0 = (a + b)/2;
            n = 0;
            max_in = 1000;
            while n < max_in
                n = n + 1;
                x1 = x0 - double(F(x0))/double(dF(x0));
                if abs(x1 - x0) < err
                    x = x1;
                return;
                end
                x0 = x1;
            end
        x = x1;  % Nếu vượt quá số lần lặp tối đa
        uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end
        
        function [x, n] = daycung(app, fx, a, b, err)
            syms x;
            try
                f_sym = str2sym(fx);
                df_sym = diff(f_sym);
                ddf_sym = diff(f_sym,2);

                fx = matlabFunction(f_sym);
                df = matlabFunction(df_sym);
                ddf = matlabFunction(ddf_sym);
            catch
                uialert(app.UIFigure, 'Lỗi cú pháp hàm số', 'Lỗi Input');
                x = NaN; n = 0; return;
            end

            if fx(a)*fx(b) >= 0
                uialert(app.UIFigure, 'Khoảng [a, b] không là khoảng phân ly!', 'Lỗi điều kiện');
                x = NaN; n = 0; return;
            end

            if df(a)*df(b) <= 0 || ddf(a)*ddf(b) <= 0
                uialert(app.UIFigure, 'Không hội tụ! Không thể dùng phương pháp dây cung.', 'Lỗi điều kiện');
                x = NaN; n = 0; return;
            else
                x0 = a;
                d = b;
                if fx(x0)*ddf(x0) > 0 && fx(d)*fx(x0)>0
                    x0 = b;
                    d = a;
                end

                n = 0;
                while n < 1000
                    n = n + 1;
                    x = x0 - (d-x0)*(fx(x0))/(fx(d)-fx(x0));
                    if abs(x - x0) < err
                        disp(x);
                        return;
                    end
                    if n == 1000
                        uialert(app.UIFigure, ['Lưu ý: Phương pháp đã đủ 1000 lần lặp nhưng chưa đạt sai số mong muốn!', 'Cảnh báo']);
                    end
                    x0 = x;
                end
            end
        end
        %% Hàm giải hệ phương trình
        function [X, L, U, Y] = LU_analysis(app, A, B)
            %disp(A);
            [n, m] = size(A);

            % --- Ma trận A phải là ma trận vuông ---
            if n ~= m
                uialert(app.UIFigure, 'Ma trận A phải là ma trận vuông!', 'Lỗi kích thước');
                error('Input matrix A must be square!'); %Dừng hàm
            end

            % --- Kiểm tra B có khớp với A không ---
            [nB, mB] = size(B);
            % Đảm bảo B là vector cột
            if mB ~= 1
                % Nếu lỡ nhập hàng ngang thì chuyển vị luôn
                if nB == 1 && mB == m
                    B = B';
                    nB = mB;
                else
                    uialert(app.UIFigure, 'Vector B phải có kích thước N x 1!', 'Lỗi kích thước');
                    error("Vector B dimension mismatch!");
                end
            end

            if nB ~= n
                uialert(app.UIFigure, 'Số hàng của A và B không khớp nhau!', 'Lỗi kích thước');
                error("Dimension mismatch between A and B!");
            end

            % ----- Thuật toán -----
            L = eye(n); 
            U = zeros(n,n);
    
            % B1: Phân tích A = L.U
            U(1,:) = A(1,:); % Khởi tạo hàng đầu của U

            % Kiểm tra phần tử đầu tiên có bằng 0 không
            if abs(U(1,1)) < 1e-12
                uialert(app.UIFigure, 'Phần tử U(1,1) bằng 0. Không thể chia!', 'Lỗi chia 0');
                error('Division by zero detected');
            end
    
            for k = 2:n
                for l = 1:k-1
                    if l == 1
                        L(k,l) = A(k,l)/U(l,l);
                    else
                        temp = sum(L(k,1:l-1)*U(1:l-1,l));
                        L(k,l) = (A(k,l) - temp)/U(l,l);
                    end
                end

                for f = k:m
                    temp = sum(L(k,1:k-1)*U(1:k-1,f));
                    U(k,f) = A(k,f) - temp;
                end
            end
            %disp(L); disp(U);

            % Check phần từ cuối cùng của U trước khi phải ngược
            if abs(U(n,n)) < 1e-12
                uialert(app.UIFigure, 'Ma trận suy biến. Không có nghiệm duy nhất!.', 'Lỗi toán học');
                X=[]; L=[]; U=[]; Y=[]; return;
            end

            % B2: L.Y = B -> Tìm Y
            Y = zeros(n,1);
            Y(1,1) = B(1,1)/L(1,1);
            for k = 2:n
                Y(k,1) = (B(k,1) - sum(L(k,1:k-1)*Y(1:k-1,1)))/L(k,k);
            end
            %disp(Y);

            % B2: U.X = Y
            X = zeros(n,1);
            X(n,1) = Y(n,1)/U(n,n);
            for k = n-1:-1:1
                X(k,1) = (Y(k,1) - sum(U(k,n:-1:k+1)*X(n:-1:k+1,1)))/U(k,k);
            end
            %disp(X);
        end

        function [X, process_log] = Gauss(app, A, b)
            
            [nA, mA] = size(A);
            % --- Ma trận A phải là ma trận vuông ---
            if nA ~= mA
                uialert(app.UIFigure, 'Ma trận A phải là ma trận vuông!', 'Lỗi kích thước');
                error('Input matrix A must be square!'); %Dừng hàm
            end

            % Đảm bảo b là cột
            if size(b, 2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'lỗi kích thước');
                X=[]; process_log=""; return;
            end

            % ----- Thuật toán -----
            Ab = [A, b];
            [m, n] = size(Ab);

            % Khởi tạo log
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline;
            % ----- Quá trình thuận -----
            for k = 1:m
                % Kiểm tra phần trụ bằng 0
                pivot = Ab(k,k);
                if abs(pivot) < 1e-12
                    uialert(app.UIFigure, ['Phần tử chia tại dòng '  num2str(k) ' bằng 0. Không thể giải tiếp!'], 'Lỗi tính toán');
                    X=[]; return;
                end

                Ab(k,:) = Ab(k,:) ./ Ab(k,k); % Chuẩn hóa
                for l = (k+1):m
                    Ab(l,:) = Ab(l,:) - Ab(l,k).*Ab(k,:);% Khử x
                end
                %disp(Ab);

                process_log = process_log + "--- Chuẩn hóa hàng " + k + " và khử cột " + k + " ---" + newline;
                process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline;
            end

            % ----- Quá trình nghịch -----
            X = zeros(m,1);
            X(m,1) = Ab(m,n)./Ab(m,m);
            for k = (m-1):-1:1
                X(k) = (Ab(k,n) - Ab(k,k+1:m)*X(k+1:m)) / Ab(k,k);
            end
            %disp(X);
            process_log = process_log + "--- Kết quả nghiệm x ---" + newline;
            process_log = process_log + join(string(num2str(X, '%.4f ')), newline);
        end

        function [X, process_log] = GaussJordan(app, A, b)
            [nA, mA] = size(A);
            % Ma trận A phải là ma trận vuông
            if(nA ~= mA)
                uiAlert(app.UIFigure,"Ma trận A không phải ma trận vuông", "Lỗi kích thước");
                error("Input matrix A must be square!");
            end

            % Đảm bảo b là cột
            if size(b,2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'Lỗi kích thước');
                X = []; process_log = "";return;
            end

            % Thuật toán
            Ab = [A b];
            [m, n] = size(Ab);
            % Quá trình thuận
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str([A b], '%.4f    ')), newline) + newline + newline;
            for k = 1:m
                % Kiểm tra phần trụ bằng 0
                pivot = Ab(k,k);
                if abs(pivot) < 1e-12
                    uialert(app.UIFigure, ['Phần tử chia tại dòng '  num2str(k) 'bằng 0. Không thể giải tiếp!'], 'Lỗi tính toán');
                    X=[]; return;
                end
                Ab(k,:) = Ab(k,:) ./ Ab(k,k);
                for l = 1:m
                    if l ~= k
                        Ab(l, :) = Ab(l,:) - Ab(l,k) .* Ab(k,:);
                    end
                end
                process_log = process_log + "--- Chuẩn hóa hàng " + num2str(k) + " khử cột " + num2str(k) + ' ---' + newline;
                process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline; 
            end

            % Quá trình nghịch
            X = size(m,1);
            X(m,1) = Ab(m,n)/Ab(m,m);
            for k = (m-1):-1:1
                X(k) = (Ab(k,n) - Ab(k,k+1:m)*X(k+1:m))/Ab(k,k);
            end
            process_log = process_log + "--- Kết quả nghiệm x ---" + newline;
            process_log = process_log + join(string(num2str(X, '%.4f ')), newline);
        end

        function [X, process_log] = GaussTruToiDai(app, A, b)
            [nA, mA] = size(A);
            % Ma trận A phải là ma trận vuông
            if(nA ~= mA)
                uiAlert(app.UIFigure,"Ma trận A không phải ma trận vuông", "Lỗi kích thước");
                error("Input matrix A must be square!");
            end

            % Đảm bảo b là cột
            if size(b,2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'Lỗi kích thước');
                X = []; process_log = "";return;
            end
            
            % Thuật toán
            Ab = [A b];
            [mA, nA] = size(Ab);
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline;
            % Quá trình thuận
            for k = 1:mA
                maxNum = abs(Ab(k,k));
                maxRow = k;
                for p = k+1:mA
                    if abs(Ab(p,k)) > maxNum
                        maxNum = abs(Ab(p,k));
                        maxRow = p;
                    end
                end

                if maxRow ~= k
                    temp = Ab(k, :);
                    Ab(k,:) = Ab(maxRow,:);
                    Ab(maxRow,:) = temp;
                    process_log = process_log + "--- Thay đổi trụ tối đại: Hàng " + num2str(maxRow) + " với " + num2str(k) + " ---" + newline;
                    process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline;
                end
                
                Ab(k,:) = Ab(k,:) ./ Ab(k,k);
                for l = k+1:mA
                    Ab(l,:) = Ab(l,:) - Ab(l,k).*Ab(k,:);
                end
                process_log = process_log + "--- Chuẩn hóa hàng " + k + " và khử cột " + k + " ---" + newline;
                process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline;
            end
            disp(Ab);
            % Quá trình nghịch
            X = zeros(mA, 1);
            X(mA,1) = Ab(mA,nA)/Ab(mA,mA);
            for k = (mA-1):-1:1
                X(k) = (Ab(k,nA) - Ab(k, k+1:mA)*X(k+1:mA))/Ab(k,k);
            end
            process_log = process_log + "--- Kết quả (Vector X) ---" + newline;
            process_log = process_log + join(string(num2str(X, '%.4f')), newline);
        end
        
        function [X, process_log] = GaussSeidel(app, A, b, err)
            [nA, mA] = size(A);
            % Ma trận A phải là ma trận vuông
            if(nA ~= mA)
                uiAlert(app.UIFigure,"Ma trận A không phải ma trận vuông", "Lỗi kích thước");
                error("Input matrix A must be square!");
            end

            % Đảm bảo b là cột
            if size(b,2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'Lỗi kích thước');
                X = []; process_log = "";return;
            end
            
            % Thuật toán
            X = zeros(nA,1); n = 0; maxN = 1000; 
            Ab = [A b];
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str(Ab, '%.4f   ')), newline) + newline + newline;

            while n < maxN
                n = n + 1;
                xPrev = X;
                process_log = process_log + "--- Kết quả lần lặp thứ: " + num2str(n) + " ---" + newline;
                for k = 1:nA
                    sum_val = 0;
                    for l = 1:nA
                        if l ~= k
                            sum_val = sum_val + A(k,l)*xPrev(l,1);
                        end
                    end
                    X(k,1) = (b(k,1) - sum_val)/A(k,k);
                end
                process_log = process_log + join(string(num2str(X, '%.4f    ')), newline) + newline + newline;

                if max(abs(X - xPrev)) < err
                    process_log = process_log + "--- Kết quả (Vector X) với sai số <" + num2str(err) + " ---" + newline;
                    process_log = process_log + join(string(num2str(X, '%.4f    ')), newline);
                    return;
                end
            end
        end
        
        function [X, process_log] = Jacobi(app, A, b, err)
            [nA, mA] = size(A);
            % Ma trận A phải là ma trận vuông
            if(nA ~= mA)
                uiAlert(app.UIFigure,"Ma trận A không phải ma trận vuông", "Lỗi kích thước");
                error("Input matrix A must be square!");
            end

            % Đảm bảo b là cột
            if size(b,2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'Lỗi kích thước');
                X = []; process_log = "";return;
            end
            
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str([A b], '%.4f    ')), newline) + newline + newline;

            % Thuật toán
            C = zeros(nA);
            G = zeros(nA,1);

            for k = 1:nA
                divNum = A(k,k) - 1;
                G(k) = b(k)/divNum;
                for l = 1:nA
                    if k == l
                        C(k,l) = -1/divNum;
                    else
                        C(k,l) = -A(k,l)/divNum;
                    end
                end
            end
            
            process_log = process_log + "--- Biến đổi sang dạng X = C.X + G. Ma trận [C G] ---" + newline;
            process_log = process_log + join(string(num2str([C G],'%.4f  ')),newline) + newline + newline;
            
            cNorm = zeros(nA,1);
            for k = 1:nA
                for l = 1:nA
                    cNorm(k) = cNorm(k) - abs(C(k,l));
                end
            end

            if max(cNorm) > 1
                uialert(app.UIFigure, ['Chuẩn của ma trận C là ' num2str(max(cNorm)) ' > 1. Không hội tụ! Hãy chọn phương pháp khác!'], 'Lỗi điều kiện thực hiện');
                X = []; process_log = '';return;
            end
            
            n = 0; xPrev = b; X = zeros(nA,1);
            while n < 1000
                n = n + 1;
                X = C*xPrev + G;
                if abs(X - xPrev) < err
                    process_log = process_log + "--- Kết quả với số lần lặp: " + num2str(n) + " và sai số < " + num2str(err) + " ---" + newline; 
                    process_log = process_log + join(string(num2str(X,'%.4f')),newline);
                    return;
                end
                process_log = process_log + "--- Lần lặp thứ: " + num2str(n) + " ---" + newline; 
                process_log = process_log + join(string(num2str(X,'%.4f')),newline) + newline + newline;
                xPrev = X;
            end
        end

        function [X, process_log] = Cholesky(app, A, b)
            [nA, mA] = size(A);
            % Ma trận A phải là ma trận vuông
            if(nA ~= mA)
                uiAlert(app.UIFigure,"Ma trận A không phải ma trận vuông", "Lỗi kích thước");
                error("Input matrix A must be square!");
            end

            % Đảm bảo b là cột
            if size(b,2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'Lỗi kích thước');
                X = []; process_log = "";return;
            end
            
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str([A b], '%.4f    ')), newline) + newline + newline;
            
            % Điều kiện thực hiện: Ma trận đối xứng & xác định dương
            % Đk 1: Ma trận đối xứng
            for k = 1:nA
                for l = 1:nA
                    if l ~= k
                        if A(k,l) ~= A(l,k)
                            uialert(app.UIFigure, 'Ma trận A không đối xứng, không thể thực hiện phương pháp!', 'Lỗi đặc điểm ma trận');
                            X = []; process_log = ""; return;
                        end
                    end
                end
            end

            % Thuật toán
            L = zeros(size(A));
            Y = zeros(size(b));
            X = zeros(size(b));

            % Bước 1: Tách A = L.L'
            for k = 1:mA
                for l = k:mA
                    sum_val = 0;

                    if k > 1
                        sum_val = sum(L(l,1:k-1).*L(k,1:k-1));
                    end

                    if k == l
                        val = A(k,k) - sum_val;
                        if val < 0 % Thay cho tính định thức, làm tốn thời gian
                            uialert(app.UIFigure, "Ma trận A không xác định dương! Không thể thực hiện phương pháp!", 'Lỗi điều kiện');
                            X = []; process_log = ''; return;
                            % Điều kiện 2
                        end
                        L(k,k) = sqrt(val);
                    else
                        L(l,k) = (A(l,k) - sum_val)/L(k,k);
                    end
                end
            end
            
            process_log = process_log + "--- Ma trận L ---" + newline;
            process_log = process_log + join(string(num2str(L, '%.4f    ')), newline) + newline + newline;

            % Ma trận L chuyển vị
            L_cv = L';
            process_log = process_log + "--- Ma trận L' ---" + newline;
            process_log = process_log + join(string(num2str(L_cv, '%.4f    ')), newline) + newline + newline;

            % Bước 2: L.Y = b -> Tìm Y
            for k = 1:mA
                if k == 1
                    sum_val = 0;
                else
                    sum_val = sum(Y(1:k-1)'.*L(k,1:k-1));
                end
                Y(k) = (b(k) - sum_val)./L(k,k);
            end
            
            process_log = process_log + "--- Vector Y ---" + newline;
            process_log = process_log + join(string(num2str(Y, '%.4f')), newline) + newline + newline;

            % Bước 3: L'.X = Y -> Tìm X
            for k = mA:-1:1
                if k == mA
                    sum_val = 0;
                else
                    sum_val = sum(L_cv(k,k+1:mA).*X(k+1:mA)');
                end
                X(k) = (Y(k) - sum_val)/L_cv(k,k);
            end

            process_log = process_log + "--- Vector X ---" + newline;
            process_log = process_log + join(string(num2str(X, '%.4f    ')), newline) + newline + newline;
        end

        %% Hàm nội suy
        % Nội suy Newton
        function [output, m] = divideTable(app, dataX, dataY)
            % Kiểm tra điều kiện đầu vào
            if length(dataX) ~= length(dataY)
                uialert(app.UIFigure, 'Số lượng phần tử X và Y không bằng nhau!', 'Lỗi kích thước');
                output = []; m = 0; return;
            end

            if length(dataX) < 2
                uialert(app.UIFigure, 'Vui lòng nhập ít nhất 2 điểm dữ liệu!', 'Lỗi dữ liệu');
                output = []; m = 0; return;
            end
            
            l = 2; n = length(dataY);
            output = zeros(n);
            dataY = dataY';
            output(:,1) = dataY;
            while l < (n+1)
                for k = 1:(n-l+1)
                    output(k,l) = output(k+1,l-1) - output(k,l-1);
                end
                l = l + 1;
            end
            m = l - 1;
        end

        function y = NoiSuyNewton(app, dataX, dataY, m, x_val, output)
            % Kiểm tra điều kiện đầu vào
            if length(dataX) ~= length(dataY)
                uialert(app.UIFigure, 'Số lượng phần tử X và Y không bằng nhau!', 'Lỗi kích thước');
                y = NaN; return;
            end

            if length(dataX) < 2
                uialert(app.UIFigure, 'Vui lòng nhập ít nhất 2 điểm dữ liệu!', 'Lỗi dữ liệu');
                 y = NaN;return;
            end
            
            % Cảnh báo ngoại suy
            if x_val < dataX(1) || x_val > dataX(end)
            % Dùng uiconfirm thay vì uialert để cho người dùng chọn tiếp tục hay không
                selection = uiconfirm(app.UIFigure, ...
                    'Điểm cần tính nằm ngoài khoảng dữ liệu. Sai số có thể rất lớn. Bạn có muốn tiếp tục?', ...
                    'Cảnh báo Ngoại suy', ...
                    'Options', {'Tiếp tục', 'Hủy'}, ...
                    'DefaultOption', 1, 'CancelOption', 2);
    
                if strcmp(selection, 'Hủy')
                     y = NaN;return;
                end
            end
            
            % Thuật toán
            % Đưa về dạng cột 
            dataX = dataX(:);

            % Nội suy newton tiến / lùi
            h = dataX(2) - dataX(1);
            mid = (dataX(1) + dataX(end)) / 2;
            sum_val = 0;

            if x_val < mid % Trường hợp dùng newton tiến
                t_num = (x_val - dataX(1))/h;
                for k = 2:(m-1)
                    temp_num = 1;
                    for l = 1:k
                        temp_num = temp_num*(t_num - (l-1));
                        temp_num = temp_num/l;
                    end
                    sum_val = sum_val + temp_num*output(1,k+1);
                end
                y = output(1,1) + t_num*output(1,2) + sum_val;
            else % Trường hợp dùng newton lùi
                t_num = (x_val - dataX(end))/h;
                for k = 2:(m-1)
                    temp_num = 1;
                    for l = 1:k
                        temp_num = temp_num*(t_num + (l-1));
                        temp_num = temp_num/l;
                    end
                    idx = m - k;
                    sum_val = sum_val + temp_num*output(idx, k+1);
                end
                y = output(m,1) + t_num*output(m-1,2) + sum_val;
            end
        end

        function strPoly = NoiSuyNewtonPoly(app, dataX, m, x_val, output)
            % Kiểm tra điều kiện đầu vào
            if length(dataX) ~= m
                uialert(app.UIFigure, 'Số lượng phần tử X và Y không bằng nhau!', 'Lỗi kích thước');
                strPoly = ''; return;
            end
            
            % Thuật toán
            % Đưa về dạng cột 
            dataX = dataX(:);
            
            % Viết đa thức
            syms x;

            % Nội suy newton tiến / lùi
            h = dataX(2) - dataX(1);
            mid = (dataX(1) + dataX(end)) / 2;

            if x_val < mid % Trường hợp dùng newton tiến
                polySym = output(1,1);
                t_sym = (x - dataX(1))/h;
                for k = 2:(m-1)
                    temp_sym = 1;
                    for l = 1:k
                        temp_sym = temp_sym*(t_sym - (l-1));
                        temp_sym = temp_sym/l;
                    end
                    polySym = polySym + temp_sym*output(1,k+1);
                end
                polySym = polySym + t_sym*output(1,2);
            else % Trường hợp dùng newton lùi
                polySym = output(m,1);
                t_sym = (x - dataX(end))/h;
                for k = 2:(m-1)
                    temp_sym = 1;
                    for l = 1:k
                        temp_sym = temp_sym*(t_sym + (l-1));
                        temp_sym = temp_sym/l;
                    end
                    idx = m - k;
                    polySym = polySym + temp_sym*output(idx, k+1);
                end
                polySym = polySym + t_sym*output(m-1,2);
            end
            
            if exist('syms', 'file')
                % Sửa lỗi typo: poly_sym -> polySym
                poly_final = vpa(expand(polySym), 15);
                strPoly = string(poly_final);
            else
                strPoly = "Cần Symbolic Toolbox";
            end
        end
        
        % Nội suy Lagrange
        function y = Lagrange(app, dataX, dataY, x_val)
            if length(dataX) ~= length(dataY)
                uialert(app.UIFigure, 'Số lượng phần tử X và Y không bằng nhau!', 'Lỗi kích thước');
                y = NaN; return;
            end
            
            % Thuật toán
            y = 0;
            for l = 1:length(dataX)
                temp_num = 1;
                for k = 1:length(dataX)
                    if k ~= l
                        temp_num = temp_num*(x_val - dataX(1,k))/(dataX(1,l) - dataX(1,k));
                    end
                end
                y = y + dataY(1,l)*temp_num;
            end
        end

        function strPoly = LagrangePoly(app, dataX, dataY)
            if length(dataX) ~= length(dataY)
                uialert(app.UIFigure, 'Số lượng phần tử X và Y không bằng nhau!', 'Lỗi kích thước');
                strPoly = ''; return;
            end
            
            % Thuật toán
            syms x; polySym = 0;
            for l = 1:length(dataX)
                temp_sym = 1;
                for k = 1:length(dataX)
                    if k ~= l
                        temp_sym = temp_sym*(x - dataX(1,k))/(dataX(1,l) - dataX(1,k));
                    end
                end
                polySym = polySym + dataY(1,l)*temp_sym;
            end

            if exist('syms', 'file')
                % Sửa lỗi typo: poly_sym -> polySym
                poly_final = vpa(expand(polySym), 15);
                strPoly = string(poly_final);
            else
                strPoly = "Cần Symbolic Toolbox";
            end
        end

        %% Hàm phương trình vi phân
        function [x, y] = Ole(app, fStr, a, b, y0, N)
            % Đảm bảo N là số dương
            N = round(N);

            try
                f = str2func(['@(x, y)' fStr]);

                test = f(a, y0);
                if isnan(test) || isinf(test)
                    uialert(app.UIFigure, 'Hàm không xác định tại điểm bắt đầu!', 'Lỗi toán học');
                    x = []; y =[]; return;
                end
            catch me
                uialert(app.UIFigure, 'Phương trình nhập vào bị lỗi cú pháp!', 'Lỗi cú pháp');
                x = []; y = []; return;
            end

            % Bước nhảy
            h = (b - a)/N;
            x = linspace(a, b, N+1);
            y = x; y(1) = y0;
            for k = 1:N
                y(k+1) = y(k) + h*f(x(k), y(k));
            end
        end

        function [x, y] = Heun(app, fStr, a, b, y0, N)
            % Đảm bảo N là số dương
            N = round(N);

            try
                f = str2func(['@(x, y)' fStr]);

                test = f(a, y0);
                if isnan(test) || isinf(test)
                    uialert(app.UIFigure, 'Hàm không xác định tại điểm bắt đầu!', 'Lỗi toán học');
                    x = []; y =[]; return;
                end
            catch me
                uialert(app.UIFigure, 'Phương trình nhập vào bị lỗi cú pháp!', 'Lỗi cú pháp');
                x = []; y = []; return;
            end

            h = (b-a)/N;
            x = linspace(a, b, N + 1);
            y = x;y(1) = y0;
            for k = 1:N
                y(k+1) = y(k) + h*f(x(k), y(k));
                while(1)
                    d = y(k+1);
                    y(k+1) = y(k) + h/2*((f(x(k), y(k))) + f(x(k+1), y(k+1)));
                    if abs(y(k+1) - d) < 0.01
                        break;
                    end
                end
            end
        end

        function [x, y] = EulerCaiTien(app, fStr, a, b, y0, N)
            % Đảm bảo N là số dương
            N = round(N);

            try
                f = str2func(['@(x, y)' fStr]);

                test = f(a, y0);
                if isnan(test) || isinf(test)
                    uialert(app.UIFigure, 'Hàm không xác định tại điểm bắt đầu!', 'Lỗi toán học');
                    x = []; y =[]; return;
                end
            catch me
                uialert(app.UIFigure, 'Phương trình nhập vào bị lỗi cú pháp!', 'Lỗi cú pháp');
                x = []; y = []; return;
            end

            % Bước nhảy
            h = (b - a)/N;
            x = linspace(a, b, N+1);
            y = x; y(1) = y0;
            for k = 1:N
                yMid = y(k) + (h/2)*f(x(k), y(k));
                y(k+1) = y(k) + h*f(x(k) + h/2, yMid);
            end
        end

        function [x, y] = rungeKutta(app, fStr, a, b, y0, N)
            % Đảm bảo N là số dương
            N = round(N);

            try
                f = str2func(['@(x, y)' fStr]);

                test = f(a, y0);
                if isnan(test) || isinf(test)
                    uialert(app.UIFigure, 'Hàm không xác định tại điểm bắt đầu!', 'Lỗi toán học');
                    x = []; y =[]; return;
                end
            catch me
                uialert(app.UIFigure, 'Phương trình nhập vào bị lỗi cú pháp!', 'Lỗi cú pháp');
                x = []; y = []; return;
            end

            h = (b - a)/N;
            x = linspace(a, b, N+1);
            y = x; y(1) = y0;
            % Bậc 4
            for k = 1:N
                k1 = h*f(x(k), y(k));
                k2 = h*f(x(k)+h/2, y(k)+k1/2);
                k3 = h*f(x(k)+h/2, y(k)+k2/2);
                k4 = h*f(x(k)+h, y(k)+k3);
                y(k+1) = y(k) + (k1 + 2*k2 + 2*k3 + k4)/6;
            end
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Tab giới thiệu
            data = {
                'Nghiệm', 'Bài tập 3.2 - câu 4 (T.103)', 'Tìm nghiệm gần đúng (Chia đôi, lặp, tiếp tuyến, dây cung)'
                'Giải hệ phương trình', 'Bài tập 4.3 - câu 3,4 (T.158)', 'Giải hệ bằng phương pháp trực tiếp và lặp'
                'Nội suy', 'Bài tập 5.7 (T.190)', 'Tìm giá trị cần nội suy (Newton, Lagrange)'
                'Phương trình vi phân', 'Bài tập 6.6 (T.221)', 'Giải PTVP bậc 1 (Euler, Heun, Euler cải tiến, Runge - Kutta)'
                };

            app.UITable.Data = data;
            app.UITable.ColumnWidth = {'fit', 'fit', '1x'};
            
            %% Tab tìm nghiệm
            % Các thông số khởi tạo ban đầu 
            app.AEditField.Value = 0;
            app.BEditField.Value = 0.5;
            app.EndEditField.Value = 0.5;
            app.StartEditField.Value = -0.2;
            app.errorEditField.Value = 0.01;

            % Mặc định cho listbox
            app.ppListBox.Value = 'Chia đôi';
            app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)

            % QUAN TRỌNG: Kích hoạt nút RUN tự động
            % Gọi hàm callback của nút RUN như một hàm bình thường
            % Tham số thứ 2 là event, truyền [] (rỗng) vì không click chuột thật
            RUNButtonPushed(app, []);

            %% Tab giải hệ phương trình
            app.Ma_tran_A.Value = '[4.9 1.0 0.1 1.1; 1.0 6.4 1.2 0.2; 0.1 1.2 3.6 1.1; 1.1 0.2 1.1 6.4]';
            app.Ma_tran_b.Value = '[5.0; 2.2; 3.7; 2.2]';

            app.method_drop_down.Value = 'Cholesky (Khai căn)';
            RUNButton_he_phuong_trinhPushed(app, []); % Khởi tạo chạy cho app hệ phương trình

            %% Tab nội suy
            app.XEditField.Value = '[58 58.34 58.68 59.02 59.36 59.7]';
            app.YEditField.Value = '[4303.52 4363.11 4425.17 4486.69 4548.69 4611.16]';
            app.diemNoiSuyEditField.Value = '58.17';

            app.MethodSelect.Value = 'Newton';
            RUN_noi_suyButtonPushed(app, []);

            %% Tab phương trình vi phân
            app.PhuongTrinhViPhanEditField.Value = 'y - x';
            app.ABEditField.Value = '[0, 0.5]';
            app.Y0EditField.Value = '1';
            app.NEditField.Value = '5';

            app.MethodSelect_2.Value = 'Euler';
            RUN_vi_phanButtonPushed(app, []);
        end

        % Callback function: EndEditField, RUNButton, StartEditField, 
        % ...and 1 other component
        function RUNButtonPushed(app, event)
            disp("--------- DEBUG: Nhấn nút được! ---------");
            % ----- Dữ liệu đầu vào -----
            endp = app.EndEditField.Value;
            startp = app.StartEditField.Value;
            func_str = app.inputEditField.Value;     

            % VD: x^2 -> x.^2
            draw_str = strrep(func_str, '^', '.^');
            draw_str = strrep(draw_str, '*', '.*');
            draw_str = strrep(draw_str, '/', './');
            func = str2func(['@(x)', draw_str]);
            disp(func);
            % ----- Thông số - tính toán vẽ đồ thị ------
            fs = 10000;
            t = linspace(startp, endp, fs);
            y = func(t);

            % ------ Vẽ đồ thị hàm số ------
            plot(app.UIAxes, real(t), real(y), 'b','LineWidth', 1.6);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');

            % ------ Tìm nghiệm xấp xỉ ------
            a = app.AEditField.Value;
            b = app.BEditField.Value;
            err = app.errorEditField.Value;
            
            func_calc = str2func(['@(x)', func_str]);
            selectedOption = app.ppListBox.Value;
            try
                switch selectedOption
                    case 'Chia đôi'
                        [x, n] = app.chiadoi(func_calc, a, b, err);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                    case 'Lặp'
                        [x, n] = app.lap(func_str, a, b, err);
                        yLine = @(k)(k);
                        plot(app.UIAxes, t, yLine(t), 'g-', 'LineWidth', 1.0);
                        xline(app.UIAxes, x, 'k--', 'LineWidth', 1.0);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, real(x), 0, 'ro', 'MarkerFaceColor', 'r');
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        hold(app.UIAxes, 'off');
                    case 'Newton (tiếp tuyến)'
                        [x, n] = app.newton_raphson(func_str, a, b, err);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                    case 'Dây cung'
                        [x, n] = app.daycung(func_str, a, b, err);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                end
                
                % Hiển thị kết quả
                app.nEditField.Value = n;
                app.resultEditField.Value = double(x);
               
            catch ME
                %uialert(app.UIFigure, ME.message, 'Lỗi');%Debug
            end
            
        end

        % Value changed function: inputEditField
        function inputEditFieldValueChanged(app, event)
            currentStr = app.inputEditField.Value;
            if isempty(currentStr)
                app.flag = 0;

                currentMethod = app.ppListBox.Value;
                switch currentMethod
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Dây cung'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else
                app.flag = 1;
            end
        end

        % Callback function
        function StartEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function EndEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ppListBox
        function ppListBoxValueChanged(app, event)
            selectedOption = app.ppListBox.Value;

            if app.flag == 0
                switch selectedOption
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Dây cung'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else 
                return;
            end
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.StartEditField.Value = app.AEditField.Value + app.c;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            if app.BEditField.Value > 0
                app.c = 2;
            end
            app.EndEditField.Value = app.BEditField.Value + app.c;
        end

        % Callback function
        function errorEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: resultEditField
        function resultEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function RUNButtonPushed2(app, event)
            
        end

        % Value changed function: method_drop_down
        function method_drop_downValueChanged(app, event)
            %value = app.method_drop_down.Value;
            
        end

        % Button pushed function: RUNButton_he_phuong_trinh
        function RUNButton_he_phuong_trinhPushed(app, event)
            strArray_A = app.Ma_tran_A.Value;
            strArray_b = app.Ma_tran_b.Value;

            numArray_A = str2num(strArray_A);
            numArray_b = str2num(strArray_b);

            % Kiểm tra người dùng có nhập đúng không
            if isempty(numArray_A) || isempty(numArray_b)
                uialert(app.UIFigure, 'Vui lòng nhập đúng định dạng mảng số!', 'Lỗi nhập liệu');
                return;
            end
            
            try
                optionDropDown_hpt = app.method_drop_down.Value;

                switch optionDropDown_hpt
                    case "Gauss"
                        [rHe_pt, process_log] = app.Gauss(numArray_A, numArray_b);
                        % Log quá trình tính toán 
                        app.qua_trinh_giai_he.Value = process_log;
                        % In ra ô kết quả
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');

                    case "Gauss - Jordan"
                        [rHe_pt, process_log] = app.GaussJordan(numArray_A, numArray_b);
                        app.qua_trinh_giai_he.Value = process_log;
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');
                    
                    case "Gauss - Trụ tối đại"
                        [rHe_pt, process_log] = app.GaussTruToiDai(numArray_A, numArray_b);
                        app.qua_trinh_giai_he.Value = process_log;
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');

                    case "Phân tách LU"
                        [rHe_pt, L, U, Y] = app.LU_analysis(numArray_A, numArray_b);

                        % Log quá trình tính toán ra Text Area
                        log_text = "";
                        log_text = log_text + "--- Ma trận A ---" + newline;
                        log_text = log_text + join(string(num2str(numArray_A)), newline) + newline + newline;
                        log_text = log_text + "--- Ma trận L ---" + newline;
                        log_text = log_text + join(string(num2str(L, '%.4f   ')), newline) + newline + newline;
                        log_text = log_text + "--- Ma trận U ---" + newline;
                        log_text = log_text + join(string(num2str(U, '%.4f   ')), newline) + newline + newline;
                        log_text = log_text + "--- Vector trung gian Y ---" + newline;
                        log_text = log_text + join(string(num2str(Y, '%.4f   ')), newline) + newline + newline;
                        log_text = log_text + "--- Kết quả (Vector x) ---" + newline;
                        log_text = log_text + join(string(num2str(rHe_pt, '%.4f   ')), newline) + newline + newline;
                        app.qua_trinh_giai_he.Value = log_text;

                        % In ra ô kết quả
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');

                    case "Cholesky (Khai căn)"
                        [rHe_pt, process_log] = app.Cholesky(numArray_A, numArray_b);
                        app.qua_trinh_giai_he.Value = process_log;
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');

                    case "Lặp Jacobi"
                        [rHe_pt, process_log] = app.Jacobi(numArray_A, numArray_b, 1e-5);
                        app.qua_trinh_giai_he.Value = process_log;
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');
                    case "Gauss - Seidel"
                        [rHe_pt, process_log] = app.GaussSeidel(numArray_A, numArray_b, 1e-5);
                        app.qua_trinh_giai_he.Value = process_log;
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');
                end
            catch me
                uialert(app.UIFigure, ['Lỗi tính toán: ' me.message], 'Lỗi');
            end
        end

        % Value changed function: Ma_tran_ket_qua
        function Ma_tran_ket_quaValueChanged(app, event)
            %value = app.Ma_tran_ket_qua.Value;
            
        end

        % Value changed function: qua_trinh_giai_he
        function qua_trinh_giai_heValueChanged(app, event)
            %value = app.qua_trinh_giai_he.Value;
            
        end

        % Callback function: RUN_noi_suy, diemNoiSuyEditField
        function RUN_noi_suyButtonPushed(app, event)
            dataX_str = app.XEditField.Value;
            dataY_str = app.YEditField.Value;
            x_str = app.diemNoiSuyEditField.Value;
            
            dataX = str2num(dataX_str);
            dataY = str2num(dataY_str);
            x = str2num(x_str);

            % Kiểm tra người dùng có nhập đúng không
            if isempty(dataX) || isempty(dataY) || length(dataX) ~= length(dataY)
                uialert(app.UIFigure, 'Vui lòng nhập đúng định dạng mảng số!', 'Lỗi nhập liệu');
                return;
            end
            
            try 
                cla(app.UIAxes2); % Clear Axes: Xóa sạch nét vẽ cũ
                grid(app.UIAxes2, 'on');
                hold(app.UIAxes2, 'on');
                optionMethod = app.MethodSelect.Value;
                switch optionMethod
                    case 'Newton'
                        %Tạo bảng sai phân hữu hạn
                        [output, m] = app.divideTable(dataX, dataY);
                        if m == 0, return; end % Nếu lỗi thì dừng
                        y = app.NoiSuyNewton(dataX, dataY, m, x, output);
                        strPoly = app.NoiSuyNewtonPoly(dataX, m, x, output);
                        app.KetquaEditField.Value = num2str(y, '%.2f');
                        app.daThucEditField.Value = strPoly;
                        
                        % Vẽ nội suy giữa các điểm
                        xGraph = linspace(min(dataX), max(dataX), 100);
                        yGraph = zeros(size(xGraph));
                        
                        %Vẽ 100 điểm
                        for k = 1:length(xGraph)
                            yGraph(k) = app.NoiSuyNewton(dataX, dataY, m, xGraph(k), output);
                        end

                        mid = (dataX(1) + dataX(end))/2;
                        if x < mid
                            app.statusLabel.Text = "Trạng thái: Đang dùng Newton tiến";
                            app.statusLabel.FontColor = [0 0.5 0]; % Màu xanh lá
                        else
                            app.statusLabel.Text = "Trạng thái: Đang dùng Newton lùi";
                            app.statusLabel.FontColor = [0 0 1]; % Màu xanh dương
                        end

                        plot(app.UIAxes2, xGraph, yGraph, 'green','LineWidth', 1.5);
                        plot(app.UIAxes2, dataX, dataY, 'ko','MarkerFaceColor', 'k');
                        stem(app.UIAxes2, x, y, 'r*', 'LineWidth', 2, 'MarkerSize',10);
                        legend(app.UIAxes2, 'Đường nội suy', 'Dữ liệu gốc', 'Kết quả');

                    case 'Lagrange'
                        y = app.Lagrange(dataX, dataY, x);
                        strPoly = app.LagrangePoly(dataX, dataY);
                        app.KetquaEditField.Value = num2str(y, '%.2f');
                        app.daThucEditField.Value = strPoly;

                        app.statusLabel.Text = "";

                        % Vẽ nội suy giữa các điểm
                        xGraph = linspace(min(dataX), max(dataX), 100);
                        yGraph = zeros(size(xGraph));
                        for k = 1:length(xGraph)
                            yGraph(k) = app.Lagrange(dataX, dataY, xGraph(k));
                        end
                        plot(app.UIAxes2, xGraph, yGraph, 'green','LineWidth', 1.5);
                        plot(app.UIAxes2, dataX, dataY, 'ko','MarkerFaceColor', 'k');
                        stem(app.UIAxes2, x, y, 'r*', 'LineWidth', 2, 'MarkerSize',10);
                        legend(app.UIAxes2, 'Đường nội suy', 'Dữ liệu gốc', 'Kết quả');
                end
                hold(app.UIAxes2, 'off');
            catch me
                uialert(app.UIFigure, ['Lỗi tính toán: ' me.message], 'Lỗi');
            end

        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            %value = app.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            %value = app.YEditField.Value;
            
        end

        % Callback function
        function diemNoiSuyEditFieldValueChanged(app, event)
            %value = app.diemNoiSuyEditField.Value;
            
        end

        % Value changed function: MethodSelect
        function MethodSelectValueChanged(app, event)
            %value = app.MethodSelect.Value;
            
        end

        % Value changed function: KetquaEditField
        function KetquaEditFieldValueChanged(app, event)
            %value = app.KetquaEditField.Value;
            
        end

        % Value changed function: daThucEditField
        function daThucEditFieldValueChanged(app, event)
            %value = app.daThucEditField.Value;
            
        end

        % Value changed function: PhuongTrinhViPhanEditField
        function PhuongTrinhViPhanEditFieldValueChanged(app, event)
            %value = app.PhuongTrinhViPhanEditField.Value;
            
        end

        % Value changed function: ABEditField
        function ABEditFieldValueChanged(app, event)
            %value = app.ABEditField.Value;
            
        end

        % Callback function
        function Y0EditFieldValueChanged(app, event)
            %value = app.Y0EditField.Value;
            
        end

        % Callback function
        function NEditFieldValueChanged(app, event)
            %value = app.NEditField.Value;
            
        end

        % Callback function
        function YxEditFieldValueChanged(app, event)
            %value = app.YxEditField.Value;
            
        end

        % Value changed function: MethodSelect_2
        function MethodSelect_2ValueChanged(app, event)
            %value = app.MethodSelect_2.Value;
            
        end

        % Callback function: NEditField, RUN_vi_phan, Y0EditField
        function RUN_vi_phanButtonPushed(app, event)
            fStr = app.PhuongTrinhViPhanEditField.Value;
            AB = str2num(app.ABEditField.Value);
            y0 = str2num(app.Y0EditField.Value);
            N = str2num(app.NEditField.Value);
            
            if N <= 0
                uialert(app.UIFigure, 'Số mẫu không hợp lệ! Bước nhảy h quá lớn hoặc nhỏ hơn bằng 0!', 'Lỗi');
                return;
            end

            try
                selectOption = app.MethodSelect_2.Value;
                cla(app.UIAxes3);
                grid(app.UIAxes3, 'on');
                hold(app.UIAxes3, 'on');
                switch selectOption
                    case 'Euler'
                        [x, y] = app.Ole(fStr, AB(1), AB(2), y0, N);
                        % disp(['Kích thước X: ', num2str(length(x))]);
                        % disp(['Kích thước Y: ', num2str(length(y))]);
                        plot(app.UIAxes3, x, y, 'g', 'LineWidth', 1.5);
                        stem(app.UIAxes3, x, y, 'r*', 'LineWidth', 1);
                        legend(app.UIAxes3, 'Euler');
                    case 'Heun'
                        [x, y] = app.Heun(fStr, AB(1), AB(2), y0, N);
                        plot(app.UIAxes3, x, y, 'g', 'LineWidth', 1.5);
                        stem(app.UIAxes3, x, y, 'r*', 'LineWidth', 1);
                        legend(app.UIAxes3, 'Heun');
                    case 'Euler cải tiến'
                        [x, y] = app.EulerCaiTien(fStr, AB(1), AB(2), y0, N);
                        plot(app.UIAxes3, x, y, 'g', 'LineWidth', 1.5);
                        stem(app.UIAxes3, x, y, 'r*', 'LineWidth', 1);
                        legend(app.UIAxes3, 'Euler cải tiến');
                    case 'Runge - Kutta'
                        [x, y] = app.rungeKutta(fStr, AB(1), AB(2), y0, N);
                        plot(app.UIAxes3, x, y, 'g', 'LineWidth', 1.5);
                        stem(app.UIAxes3, x, y, 'r*', 'LineWidth', 1);
                        legend(app.UIAxes3, 'Runge - Kutta');
                    case 'So sánh'
                        f_ode = str2func(['@(x, y)',fStr]);
                        tspan = linspace(AB(1), AB(2), N+1);
                        [x_45, y_45] = ode45(f_ode, tspan, y0);
                        % Chuẩn của Matlab
                        stem(app.UIAxes3, x_45, y_45, '--', 'LineWidth', 2);
                        leg45 = 'ODE45 (Chuẩn)';

                        [xOle, yOle] = app.Ole(fStr, AB(1), AB(2), y0, N);
                        plot(app.UIAxes3, xOle, yOle, 'LineWidth', 1.5);
                        legOle = "Euler (e: " + num2str(abs(yOle(end) - y_45(end)), '%.4f') + ")";

                        [xHeun, yHeun] = app.Heun(fStr, AB(1), AB(2), y0, N);
                        plot(app.UIAxes3, xHeun, yHeun, 'LineWidth', 1.5);
                        legHeun = "Heun (e: " + num2str(abs(yHeun(end) - y_45(end)), '%.4f') + ")";

                        [x, y] = app.EulerCaiTien(fStr, AB(1), AB(2), y0, N);
                        plot(app.UIAxes3, x, y, 'LineWidth', 1.5);
                        legEC = "Euler cải tiến(e: " + num2str(abs(y(end) - y_45(end)), '%.4f') + ")";

                        [xRK, yRK] = app.rungeKutta(fStr, AB(1), AB(2), y0, N);
                        plot(app.UIAxes3, xRK, yRK, 'LineWidth', 1.5);
                        legRK = "RK bậc 4 (e: " + num2str(abs(yRK(end) - y_45(end)), '%.4f') + ")";
                        
                        legend(app.UIAxes3, leg45, legOle, legHeun, legEC, legRK, 'Location', 'best');
                end
                hold(app.UIAxes3, 'off');
            catch me
                uialert(app.UIFigure, me.message, 'Lỗi tính toán');
            end
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            %value = app.xEditField.Value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GioiThieuTab = uitab(app.TabGroup);
            app.GioiThieuTab.Title = 'Giới thiệu';
            app.MnhcPhngphptnhLabel = uilabel(app.GioiThieuTab);
            app.MnhcPhngphptnhLabel.Text = 'Môn học: Phương pháp tính';
            app.nngdnggiitonphngphptnhLabel = uilabel(app.GioiThieuTab);
            app.nngdnggiitonphngphptnhLabel.Text = 'Đồ án: Ứng dụng giải toán phương pháp tính';
            app.HtnBiGiaHuyLabel = uilabel(app.GioiThieuTab);
            app.HtnBiGiaHuyLabel.Text = 'Họ tên: Bùi Gia Huy';
            app.Lp23DTV_CLC1Label = uilabel(app.GioiThieuTab);
            app.Lp23DTV_CLC1Label.Text = 'Lớp: 23DTV_CLC1';
            app.MSSV23207064Label = uilabel(app.GioiThieuTab);
            app.MSSV23207064Label.Text = 'MSSV: 23207064';
            app.Image = uiimage(app.GioiThieuTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images.png');
            app.UITable = uitable(app.GioiThieuTab);
            app.UITable.ColumnName = {'Tab'; 'Bài tập'; 'Mô tả'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Image2 = uiimage(app.GioiThieuTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'image_hinhanhcanhan.jpg');
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.RUNButton = uibutton(app.NghiemTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.inputEditField = uieditfield(app.NghiemTab, 'text');
            app.inputEditField.ValueChangedFcn = createCallbackFcn(app, @inputEditFieldValueChanged, true);
            app.AEditFieldLabel = uilabel(app.NghiemTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.NghiemTab, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.NghiemTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.NghiemTab, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.NhpkhongphnlynghimABLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimABLabel.Text = 'Nhập khoảng phân ly nghiệm [A, B]';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.errorEditField = uieditfield(app.NghiemTab, 'numeric');
            app.errorEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.PhngphpListBoxLabel = uilabel(app.NghiemTab);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.ppListBox = uilistbox(app.NghiemTab);
            app.ppListBox.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)', 'Dây cung'};
            app.ppListBox.ValueChangedFcn = createCallbackFcn(app, @ppListBoxValueChanged, true);
            app.ppListBox.Value = 'Chia đôi';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kêt quả';
            app.resultEditField = uieditfield(app.NghiemTab, 'numeric');
            app.resultEditField.ValueChangedFcn = createCallbackFcn(app, @resultEditFieldValueChanged, true);
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.nEditField = uieditfield(app.NghiemTab, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.NghiemTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.NghiemTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.EndEditFieldLabel = uilabel(app.NghiemTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.NghiemTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.He_phuong_trinh_Tab = uitab(app.TabGroup);
            app.He_phuong_trinh_Tab.Title = 'Giải hệ phương trình';
            app.PhngphpDropDownLabel = uilabel(app.He_phuong_trinh_Tab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.method_drop_down = uidropdown(app.He_phuong_trinh_Tab);
            app.method_drop_down.Items = {'Gauss', 'Gauss - Jordan', 'Gauss - Trụ tối đại', 'Phân tách LU', 'Cholesky (Khai căn)', 'Lặp Jacobi', 'Gauss - Seidel'};
            app.method_drop_down.ValueChangedFcn = createCallbackFcn(app, @method_drop_downValueChanged, true);
            app.method_drop_down.Value = 'Gauss';
            app.QutrnhthchinTextAreaLabel = uilabel(app.He_phuong_trinh_Tab);
            app.QutrnhthchinTextAreaLabel.Text = 'Quá trình thưc hiện';
            app.qua_trinh_giai_he = uitextarea(app.He_phuong_trinh_Tab);
            app.qua_trinh_giai_he.ValueChangedFcn = createCallbackFcn(app, @qua_trinh_giai_heValueChanged, true);
            app.RUNButton_he_phuong_trinh = uibutton(app.He_phuong_trinh_Tab, 'push');
            app.RUNButton_he_phuong_trinh.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_he_phuong_trinhPushed, true);
            app.RUNButton_he_phuong_trinh.Text = 'RUN';
            app.AEditField_2Label_2 = uilabel(app.He_phuong_trinh_Tab);
            app.AEditField_2Label_2.Text = 'A';
            app.Ma_tran_A = uieditfield(app.He_phuong_trinh_Tab, 'text');
            app.AEditField_2Label_3 = uilabel(app.He_phuong_trinh_Tab);
            app.AEditField_2Label_3.Text = 'b';
            app.Ma_tran_b = uieditfield(app.He_phuong_trinh_Tab, 'text');
            app.AEditField_2Label_4 = uilabel(app.He_phuong_trinh_Tab);
            app.AEditField_2Label_4.Text = 'Kết quả';
            app.Ma_tran_ket_qua = uieditfield(app.He_phuong_trinh_Tab, 'text');
            app.Ma_tran_ket_qua.ValueChangedFcn = createCallbackFcn(app, @Ma_tran_ket_quaValueChanged, true);
            app.Noi_suy_Tab = uitab(app.TabGroup);
            app.Noi_suy_Tab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.Noi_suy_Tab);
            title(app.UIAxes2, 'Dữ liệu')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RUN_noi_suy = uibutton(app.Noi_suy_Tab, 'push');
            app.RUN_noi_suy.ButtonPushedFcn = createCallbackFcn(app, @RUN_noi_suyButtonPushed, true);
            app.RUN_noi_suy.Text = 'RUN';
            app.PhngphpDropDownLabel_2 = uilabel(app.Noi_suy_Tab);
            app.PhngphpDropDownLabel_2.Text = 'Phương pháp';
            app.MethodSelect = uidropdown(app.Noi_suy_Tab);
            app.MethodSelect.Items = {'Newton', 'Lagrange'};
            app.MethodSelect.ValueChangedFcn = createCallbackFcn(app, @MethodSelectValueChanged, true);
            app.MethodSelect.Value = 'Newton';
            app.XEditFieldLabel = uilabel(app.Noi_suy_Tab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Noi_suy_Tab, 'text');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.Noi_suy_Tab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Noi_suy_Tab, 'text');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.KtquEditFieldLabel_2 = uilabel(app.Noi_suy_Tab);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.KetquaEditField = uieditfield(app.Noi_suy_Tab, 'text');
            app.KetquaEditField.ValueChangedFcn = createCallbackFcn(app, @KetquaEditFieldValueChanged, true);
            app.imnisuyEditFieldLabel = uilabel(app.Noi_suy_Tab);
            app.imnisuyEditFieldLabel.Text = 'Điểm nội suy';
            app.diemNoiSuyEditField = uieditfield(app.Noi_suy_Tab, 'text');
            app.diemNoiSuyEditField.ValueChangedFcn = createCallbackFcn(app, @RUN_noi_suyButtonPushed, true);
            app.statusLabel = uilabel(app.Noi_suy_Tab);
            app.statusLabel.Text = 'Status';
            app.aThcEditFieldLabel = uilabel(app.Noi_suy_Tab);
            app.aThcEditFieldLabel.Text = 'Đa Thức';
            app.daThucEditField = uieditfield(app.Noi_suy_Tab, 'text');
            app.daThucEditField.ValueChangedFcn = createCallbackFcn(app, @daThucEditFieldValueChanged, true);
            app.PhngtrnhviphnTab = uitab(app.TabGroup);
            app.PhngtrnhviphnTab.Title = 'Phương trình vi phân';
            app.UIAxes3 = uiaxes(app.PhngtrnhviphnTab);
            title(app.UIAxes3, 'Dữ liệu')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PhngtrnhviphnEditFieldLabel = uilabel(app.PhngtrnhviphnTab);
            app.PhngtrnhviphnEditFieldLabel.Text = 'Phương trình vi phân';
            app.PhuongTrinhViPhanEditField = uieditfield(app.PhngtrnhviphnTab, 'text');
            app.PhuongTrinhViPhanEditField.ValueChangedFcn = createCallbackFcn(app, @PhuongTrinhViPhanEditFieldValueChanged, true);
            app.ABLabel = uilabel(app.PhngtrnhviphnTab);
            app.ABLabel.Text = '[A, B]';
            app.ABEditField = uieditfield(app.PhngtrnhviphnTab, 'text');
            app.ABEditField.ValueChangedFcn = createCallbackFcn(app, @ABEditFieldValueChanged, true);
            app.Y_0Label = uilabel(app.PhngtrnhviphnTab);
            app.Y_0Label.Text = 'Y(0)';
            app.Y0EditField = uieditfield(app.PhngtrnhviphnTab, 'text');
            app.Y0EditField.ValueChangedFcn = createCallbackFcn(app, @RUN_vi_phanButtonPushed, true);
            app.RUN_vi_phan = uibutton(app.PhngtrnhviphnTab, 'push');
            app.RUN_vi_phan.ButtonPushedFcn = createCallbackFcn(app, @RUN_vi_phanButtonPushed, true);
            app.RUN_vi_phan.Text = 'RUN';
            app.PhngphpDropDownLabel_3 = uilabel(app.PhngtrnhviphnTab);
            app.PhngphpDropDownLabel_3.Text = 'Phương pháp';
            app.MethodSelect_2 = uidropdown(app.PhngtrnhviphnTab);
            app.MethodSelect_2.Items = {'Euler', 'Heun', 'Euler cải tiến', 'Runge - Kutta', 'So sánh'};
            app.MethodSelect_2.ValueChangedFcn = createCallbackFcn(app, @MethodSelect_2ValueChanged, true);
            app.MethodSelect_2.Value = 'Euler';
            app.NEditFieldLabel = uilabel(app.PhngtrnhviphnTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.PhngtrnhviphnTab, 'text');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @RUN_vi_phanButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_ppt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end